classdef DataInladen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        MetingenTab                matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
        GesynchroniseerdedataTab   matlab.ui.container.Tab
        UIAxes2                    matlab.ui.control.UIAxes
        ExporteerButton            matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        SynchroniseerButton        matlab.ui.control.Button
        TextArea_2                 matlab.ui.control.TextArea
        Tree                       matlab.ui.container.CheckBoxTree
        EDFNode                    matlab.ui.container.TreeNode
        PCO2Node                   matlab.ui.container.TreeNode
        PCO2_DCNode                matlab.ui.container.TreeNode
        SpO2Node                   matlab.ui.container.TreeNode
        PRNode                     matlab.ui.container.TreeNode
        GDTNode                    matlab.ui.container.TreeNode
        LoadNode                   matlab.ui.container.TreeNode
        HRNode_2                   matlab.ui.container.TreeNode
        V_O2_kgNode                matlab.ui.container.TreeNode
        V_CO2_kgNode               matlab.ui.container.TreeNode
        V_ENode                    matlab.ui.container.TreeNode
        BFNode                     matlab.ui.container.TreeNode
        EqO2Node                   matlab.ui.container.TreeNode
        EqCO2Node                  matlab.ui.container.TreeNode
        RERNode                    matlab.ui.container.TreeNode
        O2pulseNode                matlab.ui.container.TreeNode
        PaCO2Node                  matlab.ui.container.TreeNode
        SelecteerGDTbestandButton  matlab.ui.control.Button
        TextArea                   matlab.ui.control.TextArea
        SelecteerEDFbestandButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        dataEDFTimeTable % to hold EDF data
        infoedf % to hold EDF fileinfo
        fs % Sample Rate
        colortable %Specifeert welke kleur bij welke datasoort hoort
        currentlyDrawnNodes
        dataGDTTimeTable % to hold GDT data
        gdtSamplingTimesSeconds %Table of samplingtimes of GDT file in seconds
        gdtDuration
        gdtSampleRate %sample rate of GDT file
        RootMeanSquareError
        MAXIMUMRMSE = 10; %De maximumwaarde die de RMSE mag zijn na correlatie.
        
        EDFGraphs
        GDTGraphs

        isSynchronized
    end
    
    methods (Access = private)
            
        function drawEDF(app)

            if isempty(app.dataEDFTimeTable)
                return;
            end

            hold(app.UIAxes, 'on');
            set(app.UIAxes, 'XLimSpec', 'Tight');
            set(app.UIAxes, 'YLimSpec', 'Tight');
            t = app.dataEDFTimeTable.('Record Time');
            columns = app.dataEDFTimeTable.Properties.VariableNames;

            lines = [];
            for i = 1:length(columns)
                column = columns{i};

                line = plot(app.UIAxes, t, app.dataEDFTimeTable.(column), 'DisplayName',(append('EDF ', column)));
                lines = [lines, line];    
            end

            app.EDFGraphs = dictionary(string(columns), lines);
            legend(app.UIAxes);
        end

       
        function drawGDT(app)

            if isempty(app.dataGDTTimeTable)
                return;
            end


            hold(app.UIAxes,'on');
            set(app.UIAxes, 'XLimSpec', 'Tight');
            set(app.UIAxes, 'YLimSpec', 'Tight');
            t = app.dataGDTTimeTable.('Time');
            columns = app.dataGDTTimeTable.Properties.VariableNames;

            lines = [];
            for i = 1:length(columns)
                column = columns{i};

                line = plot(app.UIAxes, t, app.dataGDTTimeTable.(column), 'DisplayName',(append('GDT ', column)));
                lines = [lines, line];    
            end

            app.GDTGraphs = dictionary(string(columns), lines);
            legend(app.UIAxes);
        end
        

        function editGraph(app, event)
            negDiff = setdiff(event.PreviousLeafCheckedNodes, event.LeafCheckedNodes);
            posDiff = setdiff(event.LeafCheckedNodes, event.PreviousLeafCheckedNodes);
            
            if(~isempty(negDiff))
                for i = 1:size(negDiff, 1)
                    if ~isempty(app.EDFGraphs) && strcmp(negDiff(i).Parent.Text, 'EDF')
                        app.EDFGraphs(negDiff(i).Text).Visible = 'off';
                    elseif ~isempty(app.GDTGraphs) && strcmp(negDiff(i).Parent.Text, 'GDT')
                        app.GDTGraphs(negDiff(i).Text).Visible = 'off';
                    end
                end
            end
            
            if(~isempty(posDiff))
                for i = 1:size(posDiff, 1)
                    if ~isempty(app.EDFGraphs) && strcmp(posDiff(i).Parent.Text, 'EDF')
                        app.EDFGraphs(posDiff(i).Text).Visible = 'on';
                    elseif ~isempty(app.GDTGraphs) && strcmp(posDiff(i).Parent.Text, 'GDT')
                        app.GDTGraphs(posDiff(i).Text).Visible = 'on';
                    end
                end
            end
        end

        
        function upsample(app)

            gdtHR_Y = get(app.GDTGraphs('HR'), 'YData');
           

            upFactor=app.fs/app.gdtSampleRate;
            upGDT = interp(gdtHR_Y, upFactor);
            %upGDTDuration = interp(seconds(app.dataGDTTimeTable.('Time')), upFactor); %is enkel nodig als X niet 0:app.fs:(length(upGDT)-1) is
            % dt = mean(diff(upGDTDuration)); %foute waarde? foute manier, eventueel interp gebruiken
            % up_Fs  = 1 / dt; %sampling frequency gdt
            % up_period = 1/up_Fs; %sampling period
            % n = length(upGdt);
            % % f = (0:n-1)*(up_Fs/n); % frequency range
            % Y = fft(upGdt)/n; % Fourier transform
            % powerUpGdt = abs(Y); % power spectrum
            % display('upsampling gdt: done');
            
            x_axis=0:app.fs:(length(upGDT)-1);
            set(app.GDTGraphs('HR'), 'XData', (x_axis)) %set(app.GDTGraphs('HR'), 'XData', upGDTDuration)
            set(app.GDTGraphs('HR'), 'YData', upGDT);
            drawnow;
        
        end

        function crossCorrelate(app)
            
            s1Y = get(app.EDFGraphs('PR'), 'YData');
            s1Y = s1Y-mean(s1Y);

            s2 = get(app.GDTGraphs('HR'), 'YData');
            s2 = s2-mean(s2);

            [C,lags] = xcorr(s2,s1Y);
            C = C/max(C);%normalize
            
            [M,I] = max(C);
            t = lags(I);
            
            %draw crosscorrelation plot
            plot(app.UIAxes2,lags,C,[t t],[-0.5 1],'r:')
            text(app.UIAxes2,t+100,0.5,['Lag: ' int2str(t)])
            set(app.UIAxes2, 'XLimSpec', 'Tight');
            set(app.UIAxes2, 'YLimSpec', 'Tight');
            
        
            % Shift all curves of EDF
            lines = app.EDFGraphs.values;
            for i = 1:numel(lines)
                lineX = get(lines(i), 'XData');
                set(lines(i), 'XData', lineX+seconds(t));
            end
            
            %Limit X-axis to the shortest data measurement
            v1 = seconds(app.infoedf.NumDataRecords*app.infoedf.DataRecordDuration); %edf length
            v2 = app.gdtSamplingTimesSeconds(end); %gdt length
            shortestMeasurement = min([v1, v2]);
            app.UIAxes.XLimMode = "manual";
            app.UIAxes.XLim = [0 , duration(0,0,shortestMeasurement)];
            

            %save correlated EDF and GDT
            s1_synced=get(app.EDFGraphs('PR'), 'YData');
            s1_synced_cut = s1_synced(-t:end);
            s2_synced = get(app.GDTGraphs('HR'), 'YData');
            %calculateRMSEofHR
            if (length(s1_synced_cut) <= length(s2_synced)) %To accomodate data length problems
                app.RootMeanSquareError=rmse(s2_synced(1:length(s1_synced_cut)),s1_synced_cut);
            else
                app.RootMeanSquareError=rmse(s2_synced,s1_synced_cut(1:length(s2_synced)));
            end
            
            %Add RMSE to textarea and show error if RSME is too large
            newtext = sprintf('\nRoot Mean Square Error of correlated heartrate: %d', app.RootMeanSquareError);
            if (app.RootMeanSquareError > app.MAXIMUMRMSE)
                newtext = sprintf('\nRoot Mean Square Error of correlated heartrate: %d\nCorrelatie mislukt, RMSE is abnormaal groot', app.RootMeanSquareError);
                uiwait(msgbox("Correlatie mislukt, RMSE is abnormaal groot","modal"));
            end
            app.TextArea_2.Value{end+1} = newtext;
            
            drawnow;


        end


        function deleteGraphs(app, type)

            if strcmp(type, 'EDF')
                if ~isempty(app.EDFGraphs)
                    values = app.EDFGraphs.values;
                    for i=1:app.EDFGraphs.numEntries
                        delete(values(i));
                    end
                    app.EDFGraphs = [];
                end
                checkedNodes = get(app.Tree, 'CheckedNodes');
                set(app.Tree, 'CheckedNodes', cat(1,checkedNodes, app.EDFNode));
            elseif strcmp(type, 'GDT')
                if ~isempty(app.GDTGraphs)
                    values = app.GDTGraphs.values;
                    for i=1:app.GDTGraphs.numEntries
                        delete(values(i));
                    end
                    app.GDTGraphs = [];
                end
                checkedNodes = get(app.Tree, 'CheckedNodes');
                set(app.Tree, 'CheckedNodes', cat(1,checkedNodes, app.GDTNode));
            end
        end     

        
        function calcGDTValues(app)
            time = seconds(app.dataGDTTimeTable.('Time'));
            load = app.dataGDTTimeTable.('Load');

            totalJoules = trapz(time, load);
            totalWattHour = totalJoules/3600;

            averageLoad = totalJoules/time(end,:);


            s = sprintf('Total Energy Consumed: %.0f kJ / %.0f Wh', totalJoules/(1E3), totalWattHour);
            s = [s, sprintf('\nMax Load: %d W', max(load))];


            app.TextArea_2.Value = s;        
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.isSynchronized = 0;
            set(app.SelecteerGDTbestandButton,'Visible','Off')
            set(app.ExporteerButton,'Visible','Off')
            set(app.ResetButton,'Visible','Off')
            set(app.SynchroniseerButton,'Visible','Off')
        end

        % Button pushed function: SelecteerEDFbestandButton
        function SelecteerEDFbestandButtonPushed(app, event)
                     
            uiwait(msgbox("Selecteer EDF bestand van Capnografie metingen","modal"));   % Dialog box for opening EDF file
            f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
            [fileName,path] = uigetfile('*.edf')
            delete(f);  % delete the dummy figure
            if fileName

                deleteGraphs(app, 'EDF');
                set(app.ExporteerButton,'Visible','Off');

                app.dataEDFTimeTable = edfread(strcat(path,fileName))
                app.infoedf = edfinfo(fileName)
    
                %remove zeros from end of EDF HR
                firstoflastzero=find(app.dataEDFTimeTable.PR,1,'last') %find first of lastzeros
                app.dataEDFTimeTable = app.dataEDFTimeTable(1:firstoflastzero,:); %remove zeros from end of file
    

                %Calculate sample rate:
                numSamples = app.infoedf.NumDataRecords;
                testDuration = app.infoedf.NumDataRecords;
                app.fs=numSamples/testDuration;
    
                %Show edf parameters
                textforconsole = sprintf("Patient: " + app.infoedf.Patient + "\nDate: " + app.infoedf.StartDate + "\nTime: " + app.infoedf.StartTime + "\nNumDataRecords: " + app.infoedf.NumDataRecords + "\nSample Rate: " + app.fs + " Hz")
                app.TextArea.Value = textforconsole


                if ~isempty(app.dataGDTTimeTable)
                    deleteGraphs(app, 'GDT');
                    drawEDF(app);
                    drawGDT(app);
                else
                    drawEDF(app);
                end


                if ~isempty(app.dataGDTTimeTable)
                    set(app.ResetButton,'Visible','On')
                    set(app.SynchroniseerButton,'Visible','On')
                end
                set(app.SelecteerGDTbestandButton,'Visible','On')

            end

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            % checkedNodes = app.Tree.CheckedNodes;
            editGraph(app, event);
        end

        % Button pushed function: SelecteerGDTbestandButton
        function SelecteerGDTbestandButtonPushed(app, event)
        
        delete(app.currentlyDrawnNodes) % delete list of drawn plots
            
        uiwait(msgbox("Selecteer GDT bestand van Ergospirometrie metingen","modal"));   % Dialog box for opening GDT file
        f = figure('Renderer', 'painters', 'Position', [-100 -100 0 0]); %create a dummy figure so that uigetfile doesn't minimize our GUI
        [GDT_filename,GDT_path] = uigetfile('*GDT')    % Select GDT file
        delete(f);  % delete the dummy figure
        
        if GDT_filename

            deleteGraphs(app, 'GDT');
            set(app.ExporteerButton,'Visible','Off')

            options = detectImportOptions(strcat(GDT_path,GDT_filename), 'FileType','text');
            T = readtable(strcat(GDT_path,GDT_filename), options); 
            T.PaCO2(isnan(T.PaCO2)) = 0; %replace Nan values with 0 so they dont get removed in next step
            gdtfile = T(~any(ismissing(T),2),:);%remove all rows that have missing value or NaN. now we only have the table remaining.
            

            %convert file to timetable
            t_minutes = str2double(extractBefore(gdtfile.Time, ':'));%extract time from column  
            t_seconds = str2double(extractAfter(gdtfile.Time, ':'));
            timeDuration = duration(0, t_minutes, t_seconds);
            timeDuration = timeDuration - timeDuration(1); %remove waiting time where no data is logged
            app.gdtSamplingTimesSeconds = seconds(timeDuration); %save Time in seconds
            gdtfile.Time=[];%remove time column
            gdtTimeTable=(table2timetable(gdtfile,'RowTimes',timeDuration));
            

            %retime and interpolate to fill holes
            %gdtSamplePeriod = round(mean(diff(app.gdtSamplingTime)));%difference between the samples and we take the mean of that
            gdtSamplePeriod = mode(diff(app.gdtSamplingTimesSeconds)); %the sampleperiod that gdt is supposed to be in this case 10 (mode takes the most common)
            app.gdtSampleRate = 1/gdtSamplePeriod;
            gdtTimeTable.Properties.VariableContinuity= {'continuous','continuous','continuous','continuous','continuous','continuous','continuous','continuous','continuous','continuous','continuous'};
            gdtTimeTable=retime(gdtTimeTable,'regular','SampleRate',app.gdtSampleRate);
            %gdtTimes = seconds(duration(gdtfile.Time));
            app.dataGDTTimeTable=gdtTimeTable;
            %old code
            %gdtfile.Time=[];%remove Time collumn
            %Time=transpose(seconds(0:gdtSamplePeriod:(height(gdtfile)-1)*gdtSamplePeriod ));%create new time collumn
            %app.dataGDTTimeTable=table2timetable(gdtfile,'RowTimes',Time);
            
    
            calcGDTValues(app);
            drawGDT(app)
            
            if ~isempty(app.dataEDFTimeTable)
                set(app.ResetButton,'Visible','On')
                set(app.SynchroniseerButton,'Visible','On')
            end
            set(app.SelecteerGDTbestandButton,'Visible','Off')
        end

        end

        % Button pushed function: SynchroniseerButton
        function SynchroniseerButtonPushed(app, event)
           if ~app.isSynchronized && ~isempty(app.dataGDTTimeTable) && ~isempty(app.dataEDFTimeTable)
               app.TabGroup.SelectedTab = app.GesynchroniseerdedataTab; 
               upsample(app)
               crossCorrelate(app)
               
               app.isSynchronized = 1;
               set(app.ExporteerButton,'Visible','On')
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UIAxes.ColorOrderIndex = 1;
            
            deleteGraphs(app, 'EDF');
            deleteGraphs(app, 'GDT');
           
            drawEDF(app);
            drawGDT(app);

            app.UIAxes.XLimMode = "auto";

            app.isSynchronized = 0;
            set(app.ExporteerButton,'Visible','Off');
        end

        % Button pushed function: ExporteerButton
        function ExporteerButtonPushed(app, event)
            [fileName,path] = uiputfile('*.xlsx');
            if fileName
                edf_cor=transpose(get(app.EDFGraphs('PR'),'YData'));
                upGdt=transpose(get(app.GDTGraphs('HR'),'YData'));
                maxLen = max([numel(edf_cor), numel(upGdt)]);
                
                
                % Convert vectors to cell arrays with NaN padding
                cellVector1 = num2cell([edf_cor; NaN(maxLen - numel(edf_cor), 1)]);
                cellVector2 = num2cell([upGdt; NaN(maxLen - numel(upGdt), 1)]);
                signalNames = { 'Edf synced','Gdt upsampled+synced'};
                signalTable = table(cellVector1, cellVector2, 'VariableNames', signalNames);
                writetable(signalTable, strcat(path,fileName), 'Sheet', 1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SelecteerEDFbestandButton = uibutton(app.UIFigure, 'push');
            app.SelecteerEDFbestandButton.ButtonPushedFcn = createCallbackFcn(app, @SelecteerEDFbestandButtonPushed, true);
            app.SelecteerEDFbestandButton.Text = 'Selecteer EDF bestand';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.SelecteerGDTbestandButton = uibutton(app.UIFigure, 'push');
            app.SelecteerGDTbestandButton.ButtonPushedFcn = createCallbackFcn(app, @SelecteerGDTbestandButtonPushed, true);
            app.SelecteerGDTbestandButton.Text = 'Selecteer GDT bestand';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.EDFNode = uitreenode(app.Tree);
            app.EDFNode.Text = 'EDF';
            app.PCO2Node = uitreenode(app.EDFNode);
            app.PCO2Node.Text = 'PCO2';
            app.PCO2_DCNode = uitreenode(app.EDFNode);
            app.PCO2_DCNode.Text = 'PCO2_DC';
            app.SpO2Node = uitreenode(app.EDFNode);
            app.SpO2Node.Text = 'SpO2';
            app.PRNode = uitreenode(app.EDFNode);
            app.PRNode.Text = 'PR';
            app.GDTNode = uitreenode(app.Tree);
            app.GDTNode.Text = 'GDT';
            app.LoadNode = uitreenode(app.GDTNode);
            app.LoadNode.Text = 'Load';
            app.HRNode_2 = uitreenode(app.GDTNode);
            app.HRNode_2.Text = 'HR';
            app.V_O2_kgNode = uitreenode(app.GDTNode);
            app.V_O2_kgNode.Text = 'V_O2_kg';
            app.V_CO2_kgNode = uitreenode(app.GDTNode);
            app.V_CO2_kgNode.Text = 'V_CO2_kg';
            app.V_ENode = uitreenode(app.GDTNode);
            app.V_ENode.Text = 'V_E';
            app.BFNode = uitreenode(app.GDTNode);
            app.BFNode.Text = 'BF';
            app.EqO2Node = uitreenode(app.GDTNode);
            app.EqO2Node.Text = 'EqO2';
            app.EqCO2Node = uitreenode(app.GDTNode);
            app.EqCO2Node.Text = 'EqCO2';
            app.RERNode = uitreenode(app.GDTNode);
            app.RERNode.Text = 'RER';
            app.O2pulseNode = uitreenode(app.GDTNode);
            app.O2pulseNode.Text = 'O2pulse';
            app.PaCO2Node = uitreenode(app.GDTNode);
            app.PaCO2Node.Text = 'PaCO2';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.PCO2Node, app.PCO2_DCNode, app.SpO2Node, app.PRNode, app.GDTNode, app.EDFNode, app.V_ENode, app.V_CO2_kgNode, app.V_O2_kgNode, app.HRNode_2, app.LoadNode, app.BFNode, app.EqO2Node, app.EqCO2Node, app.RERNode, app.O2pulseNode, app.PaCO2Node];
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.Editable = 'off';
            app.SynchroniseerButton = uibutton(app.UIFigure, 'push');
            app.SynchroniseerButton.ButtonPushedFcn = createCallbackFcn(app, @SynchroniseerButtonPushed, true);
            app.SynchroniseerButton.Text = 'Synchroniseer';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ExporteerButton = uibutton(app.UIFigure, 'push');
            app.ExporteerButton.ButtonPushedFcn = createCallbackFcn(app, @ExporteerButtonPushed, true);
            app.ExporteerButton.Text = 'Exporteer';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MetingenTab = uitab(app.TabGroup);
            app.MetingenTab.Title = 'Metingen';
            app.UIAxes = uiaxes(app.MetingenTab);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'Time (seconds)')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.GesynchroniseerdedataTab = uitab(app.TabGroup);
            app.GesynchroniseerdedataTab.Title = 'Gesynchroniseerde data';
            app.UIAxes2 = uiaxes(app.GesynchroniseerdedataTab);
            title(app.UIAxes2, 'Cross-Correlations')
            ylabel(app.UIAxes2, 'C')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataInladen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end